classdef App2_R2016b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectordeZonasPanel    matlab.ui.container.Panel
        Torre1DropDownLabel     matlab.ui.control.Label
        Torre1DropDown          matlab.ui.control.DropDown
        Torre2DropDownLabel     matlab.ui.control.Label
        Torre2DropDown          matlab.ui.control.DropDown
        Torre3DropDownLabel     matlab.ui.control.Label
        Torre3DropDown          matlab.ui.control.DropDown
        Torre4DropDownLabel     matlab.ui.control.Label
        Torre4DropDown          matlab.ui.control.DropDown
        TorresDropDownLabel     matlab.ui.control.Label
        TorresDropDown          matlab.ui.control.DropDown
        MessageEditFieldLabel   matlab.ui.control.Label
        MessageEditField        matlab.ui.control.EditField
        SendButton              matlab.ui.control.Button
        ReceivedEditFieldLabel  matlab.ui.control.Label
        ReceivedEditField       matlab.ui.control.EditField
        ToSendEditFieldLabel    matlab.ui.control.Label
        ToSendEditField         matlab.ui.control.EditField
        DisplayDropDownLabel    matlab.ui.control.Label
        DisplayDropDown         matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            global bytes
            %Port Creation
            instrreset
            global puerto;
            %Port Configuration
            puerto=serial('COM8','BaudRate',9600);
            puerto.InputBufferSize = 10000000;
            %Port Open
            fopen(puerto)
            % fopen(puerto);
            bytes = puerto.BytesAvailable;
            
            global message;
            
            
            global received;
            
            global running
            
            running = 1;
            while running
                
                bytes = puerto.BytesAvailable;
                if bytes > 4
                
                fread(puerto,[1,4],'uint8');
                flush(puerto)
                end
                
                drawnow 
            end


        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            global message
            value = app.DisplayDropDown.Value;
            value
            
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        
                    else
                        
                        app.ToSendEditField.Value = message(1)
                    end
                    
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message)
                        if (aux < 127)&&(aux>-1)
                            app.ToSendEditField.Value = message
                        else
                            app.ToSendEditField.Value = '127'
                        end
                    else
                        app.ToSendEditField.Value = 'Error Non Decimal Input'
                    end
                case 'Binary'
                    
                    b = 0;
                    for c=1:length(message)
                       aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 1
                       end
                    end
                    
                    if b == 1
                        app.ToSendEditField.Value ='Error Non Binary Message';
                    else
                        if length(message) > 8
                           
                           app.ToSendEditField.Value = message(1:8);
                        else
                           app.ToSendEditField.Value = message
                        end
                    end
                    
                    
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
            end
            
            
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)
            global message;
            value = app.MessageEditField.Value;
            message = value
            
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        
                    else
                        
                        app.ToSendEditField.Value = message(1)
                    end
                
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message)
                        if (aux < 127)&&(aux>-1)
                            app.ToSendEditField.Value = message
                        else
                            app.ToSendEditField.Value = '127'
                        end
                    else
                        app.ToSendEditField.Value = 'Error Non Decimal Input'
                    end
                case 'Binary'
                    
                    b = 0;
                    for c=1:length(message)
                       aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 1
                       end
                    end
                    
                    if b == 1
                        app.ToSendEditField.Value ='Error Non Binary Message';
                    else
                        if length(message) > 8
                           
                           app.ToSendEditField.Value = message(1:8);
                        else
                           app.ToSendEditField.Value = message
                        end
                    end
                    
                
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
            end          
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            global puerto
            global message
                      
            %Data Packing
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        fwrite(puerto,0)
                    else
                        
                        fwrite(puerto,message(1))
                    end
                    
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message)
                        if (aux < 127)&&(aux>-1)
                            fwrite(puerto,aux)
                        else
                            app.ToSendEditField.Value = '127'
                        end
                    else
                        app.ToSendEditField.Value = 'Error Non Decimal Input'
                    end
                case 'Binary'
                    
                    b = 0;
                    for c=1:length(message)
                        aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 1;
                       end
                    end
                    
                    if b == 0
                        app.ToSendEditField.Value ='Error Non Binary Message';
                    else
                        if length(message) > 8
                           
                           fwrite(puerto,message(1:8))
                        end
                    end
                    
                    
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
            end


            %Updating Data Show


        end

        % Value changed function: ToSendEditField
        function ToSendEditFieldValueChanged(app, event)
            value = app.ToSendEditField.Value;
            
        end

        % Value changed function: Torre1DropDown
        function Torre1DropDownValueChanged(app, event)
            value = app.Torre1DropDown.Value;
            
        end

        % Value changed function: TorresDropDown
        function TorresDropDownValueChanged(app, event)
            value = app.TorresDropDown.Value;
            class(value)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global running
            running = 0;
            instrreset
            delete(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.SelectordeZonasPanel = uipanel(app.UIFigure);
            app.SelectordeZonasPanel.Title = 'Selector de Zonas';
            app.Torre1DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre1DropDownLabel.Text = 'Torre 1';
            app.Torre1DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre1DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre1DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre1DropDownValueChanged, true);
            app.Torre1DropDown.Value = '1';
            app.Torre2DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre2DropDownLabel.Text = 'Torre 2';
            app.Torre2DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre2DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre2DropDown.Value = '1';
            app.Torre3DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre3DropDownLabel.Text = 'Torre 3';
            app.Torre3DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre3DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre3DropDown.Value = '1';
            app.Torre4DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre4DropDownLabel.Text = 'Torre 4';
            app.Torre4DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre4DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre4DropDown.Value = '1';
            app.TorresDropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.TorresDropDownLabel.Text = '#Torres';
            app.TorresDropDown = uidropdown(app.SelectordeZonasPanel);
            app.TorresDropDown.Items = {'2', '3', '4'};
            app.TorresDropDown.ValueChangedFcn = createCallbackFcn(app, @TorresDropDownValueChanged, true);
            app.TorresDropDown.Value = '2';
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ReceivedEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedEditFieldLabel.Text = 'Received';
            app.ReceivedEditField = uieditfield(app.UIFigure, 'text');
            app.ReceivedEditField.Editable = 'off';
            app.ToSendEditFieldLabel = uilabel(app.UIFigure);
            app.ToSendEditFieldLabel.Text = 'To Send';
            app.ToSendEditField = uieditfield(app.UIFigure, 'text');
            app.ToSendEditField.ValueChangedFcn = createCallbackFcn(app, @ToSendEditFieldValueChanged, true);
            app.ToSendEditField.Editable = 'off';
            app.DisplayDropDownLabel = uilabel(app.UIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.UIFigure);
            app.DisplayDropDown.Items = {'ASCII', 'Decimal', 'Binary'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'ASCII';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2_R2016b()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end