classdef App3_R2016b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectordeZonasPanel    matlab.ui.container.Panel
        Torre1DropDownLabel     matlab.ui.control.Label
        Torre1DropDown          matlab.ui.control.DropDown
        Torre2DropDownLabel     matlab.ui.control.Label
        Torre2DropDown          matlab.ui.control.DropDown
        Torre3DropDownLabel     matlab.ui.control.Label
        Torre3DropDown          matlab.ui.control.DropDown
        Torre4DropDownLabel     matlab.ui.control.Label
        Torre4DropDown          matlab.ui.control.DropDown
        TorresDropDownLabel     matlab.ui.control.Label
        TorresDropDown          matlab.ui.control.DropDown
        MessageEditFieldLabel   matlab.ui.control.Label
        MessageEditField        matlab.ui.control.EditField
        SendButton              matlab.ui.control.Button
        ReceivedEditFieldLabel  matlab.ui.control.Label
        ReceivedEditField       matlab.ui.control.EditField
        ToSendEditFieldLabel    matlab.ui.control.Label
        ToSendEditField         matlab.ui.control.EditField
        DisplayDropDownLabel    matlab.ui.control.Label
        DisplayDropDown         matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            global bytes
            %Port Creation
            instrreset
            global puerto;
            %Port Configuration
            puerto=serial('COM2','BaudRate',9600);
            puerto.InputBufferSize = 10000000;
            %Port Open
            fopen(puerto);
            % fopen(puerto);
            bytes = puerto.BytesAvailable;
            
            global message;
            global message_in;
            message_in = '';
            global received;
            
            global running
            
            running = 1;
            while running
                
                bytes = puerto.BytesAvailable;
                if bytes > 3
                
                input = fread(puerto,[1,4],'uint8');
                %flush(puerto)
                
                min1 = input(1,1);
                min2 = input(1,2);
                min3 = input(1,3);
                min4 = input(1,4);
                bx1 = dec2bin(min1);
                bx2 = dec2bin(min2);
                bx3 = dec2bin(min3);
                bx4 = dec2bin (min4);
                
                l = length(bx1);
                for c = 1:8-l
                    bx1 = strcat('0',bx1);
                end
                
                l = length(bx2);
                for c = 1:8-l
                    bx2 = strcat('0',bx2);
                end
                
                l = length(bx3);
                for c = 1:8-l
                    bx3 = strcat('0',bx3);
                end
                
                
                l = length(bx4);
                for c = 1:8-l
                    bx4 = strcat('0',bx4);
                end
                                
                message_in = strcat(bx1(5:8),bx2(5:8));
                
                
                end
                 
               
                switch app.DisplayDropDown.Value
                    case 'ASCII'
                        aux = bin2dec(message_in);
                        cx = char(aux);
                        
                        app.ReceivedEditField.Value = cx;
                    case 'Decimal'
                        aux = bin2dec(message_in);
                        cx = num2str(aux);
                        app.ReceivedEditField.Value = cx;
                    case 'Binary'
                        app.ReceivedEditField.Value = message_in;
                        
                end
                
                drawnow 
            end


        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            global message
            global message_in;
            value = app.DisplayDropDown.Value;
           
            
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        
                    else
                        
                        app.ToSendEditField.Value = message(1);
                    end
                    app.ReceivedEditField.Value = message_in;
                    
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message);
                        if (aux < 255)&&(aux>-1)
                            app.ToSendEditField.Value = message;
                        else
                            
                            aux = str2num(message);
            
                            aux2 = dec2bin(aux);
                            
                            l = length(aux2);
                            mesax = aux2;
                            for c = 1:8-l
                                mesax = strcat('0',mesax);
                            end


                        end
                    else
                        app.ToSendEditField.Value = 'Error Non Decimal Input';
                    end
                    
                    app.ReceivedEditField.Value = message_in;
                    
                case 'Binary'
                    
                    b = 0;
                    for c=1:length(message)
                       aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 1;
                       end
                    end
                    
                    if b == 1
                        app.ToSendEditField.Value ='Error Non Binary Message';
                    else
                        if length(message) > 8
                           
                           app.ToSendEditField.Value = message(1:8);
                        else
                           app.ToSendEditField.Value = message;
                        end
                    end
                    
                    app.ReceivedEditField.Value = message_in;
                    
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
            end
            
            
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)
            global message;
            value = app.MessageEditField.Value;
            message = value;
            
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        
                    else
                        
                        app.ToSendEditField.Value = message(1);
                    end
                
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message);
                        if (aux < 255)&&(aux>-1)
                            app.ToSendEditField.Value = message;
                        else
                            app.ToSendEditField.Value = '00000000';
                        end
                    else
                        app.ToSendEditField.Value = 'Error Non Decimal Input';
                    end
                case 'Binary'
                    
                    b = 0;
                    for c=1:length(message)
                       aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 1;
                       end
                    end
                    
                    if b == 1
                        app.ToSendEditField.Value ='Error Non Binary Message';
                    else
                        if length(message) > 8
                           
                           app.ToSendEditField.Value = message(1:8);
                        else
                           app.ToSendEditField.Value = message;
                        end
                    end
                    
                
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
            end          
            
        end

        % Value changed function: ReceivedEditField
        function ReceivedEditFieldValueChanged(app, event)
            value = app.ReceivedEditField.Value;
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            global puerto
            global message
            zonas = ['001';'010';'011';'100';'101';'110';'111']; 
            
            
            switch app.TorresDropDown.Value
                case '2'
                zaux1 = str2num(app.Torre1DropDown.Value);    
                zaux2 = str2num(app.Torre2DropDown.Value);
                byte_3 = strcat('00',zonas(zaux1,:),zonas(zaux2,:));
                byte_4 = '00000000';
                case '3'
                zaux1 = str2num(app.Torre1DropDown.Value);    
                zaux2 = str2num(app.Torre2DropDown.Value);
                zaux3 = str2num(app.Torre3DropDown.Value);    
                byte_3 = strcat('00',zonas(zaux1,:),zonas(zaux2,:));
                byte_4 = strcat('00',zonas(zaux3,:),'000');
                case '4'
                zaux1 = str2num(app.Torre1DropDown.Value);    
                zaux2 = str2num(app.Torre2DropDown.Value);
                zaux3 = str2num(app.Torre3DropDown.Value);    
                zaux4 = str2num(app.Torre4DropDown.Value);
                byte_3 = strcat('00',zonas(zaux1,:),zonas(zaux2,:));
                byte_4 = strcat('00',zonas(zaux3,:),zonas(zaux4,:));                    
            end
            
            %Data Packing
            switch app.DisplayDropDown.Value
                case 'ASCII'
                    if isempty(message)
                        byte_1 = '10000000';
                        byte_2 = '01000000';
                        
                        
                    else
                        message = message(1);
                        aux = dec2bin(message);
                        l = length(aux);
                        mesax = aux;
                        for c = 1:8-l
                            mesax = strcat('0',mesax);
                        end
                        byte_1 = strcat('1000',mesax(1:4));
                        byte_2 = strcat('0000',mesax(5:8));


                    end
                    
                case 'Decimal'
                    if isstrprop(message,'digit')
                        aux = str2num(message);
                        byte_1 = '10000000';
                        byte_2 = '01000000'; 
                        
                        if (aux < 255)&&(aux>-1)


                            aux2 = dec2bin(aux);
                            
                            l = length(aux2);
                            mesax = aux2;
                            for c = 1:8-l
                                mesax = strcat('0',mesax);
                            end
            
                        byte_1 = strcat('1000',mesax(1:4));
                        byte_2 = strcat('0000',mesax(5:8));                        


                        else
                            app.ToSendEditField.Value = '00000000';
                        end
                    else
                        byte_1 = '10000000';
                        byte_2 = '01000000';
                    end
               
                    
                case 'Binary'
                    message
                    b = 1;
                    for c=1:length(message)
                        aux = message(c);
                       if(aux~='0')&&(aux~='1')
                           b = 0
                       end
                    end
                    
                    if b == 0
                        byte_1 = '10000000';
                        byte_2 = '01000000';
                    else
                        if length(message) > 8
                           mesax = message(1:8);
                        else
                            l = length(message);
                            mesax = message;
                                for c = 1:8-l
                                    mesax = strcat('0',mesax)
                                end
                        end
                        mesax
                        byte_1 = strcat('1000',mesax(1:4))
                        byte_2 = strcat('0000',mesax(5:8))                        
                        
                    end
                    
                    
                otherwise
                    app.ToSendEditField.Value ='Data Display Error';
                    
                    
            end
            baux1 = bin2dec(byte_1);
            b1 = char(baux1);
            fwrite(puerto,b1)
            b1
            
            baux2 = bin2dec(byte_2);
            b2 = char(baux2);
            fwrite(puerto,b2)
            b2
            
            baux3 = bin2dec(byte_3);
            b3 = char(baux3)    ;        
            fwrite(puerto,b3)
            b3
            
            baux4 = bin2dec(byte_4);
            b4 = char(baux4);
            fwrite(puerto,b4)
            b4


        end

        % Value changed function: ToSendEditField
        function ToSendEditFieldValueChanged(app, event)
            value = app.ToSendEditField.Value;
            
        end

        % Value changed function: Torre1DropDown
        function Torre1DropDownValueChanged(app, event)
            value = app.Torre1DropDown.Value;
            
        end

        % Value changed function: Torre2DropDown
        function Torre2DropDownValueChanged(app, event)
            value = app.Torre2DropDown.Value;
            
        end

        % Value changed function: Torre3DropDown
        function Torre3DropDownValueChanged(app, event)
            value = app.Torre3DropDown.Value;
            
        end

        % Value changed function: Torre4DropDown
        function Torre4DropDownValueChanged(app, event)
            value = app.Torre4DropDown.Value;
            
        end

        % Value changed function: TorresDropDown
        function TorresDropDownValueChanged(app, event)
            value = app.TorresDropDown.Value;
            class(value)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global running
            running = 0;
            instrreset
            delete(app);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectordeZonasPanel = uipanel(app.UIFigure);
            app.SelectordeZonasPanel.Title = 'Selector de Zonas';
            app.Torre1DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre1DropDownLabel.Text = 'Torre 1';
            app.Torre1DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre1DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre1DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre1DropDownValueChanged, true);
            app.Torre1DropDown.Value = '1';
            app.Torre2DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre2DropDownLabel.Text = 'Torre 2';
            app.Torre2DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre2DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre2DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre2DropDownValueChanged, true);
            app.Torre2DropDown.Value = '1';
            app.Torre3DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre3DropDownLabel.Text = 'Torre 3';
            app.Torre3DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre3DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre3DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre3DropDownValueChanged, true);
            app.Torre3DropDown.Value = '1';
            app.Torre4DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre4DropDownLabel.Text = 'Torre 4';
            app.Torre4DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre4DropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.Torre4DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre4DropDownValueChanged, true);
            app.Torre4DropDown.Value = '1';
            app.TorresDropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.TorresDropDownLabel.Text = '#Torres';
            app.TorresDropDown = uidropdown(app.SelectordeZonasPanel);
            app.TorresDropDown.Items = {'2', '3', '4'};
            app.TorresDropDown.ValueChangedFcn = createCallbackFcn(app, @TorresDropDownValueChanged, true);
            app.TorresDropDown.Value = '2';
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ReceivedEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedEditFieldLabel.Text = 'Received';
            app.ReceivedEditField = uieditfield(app.UIFigure, 'text');
            app.ReceivedEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivedEditFieldValueChanged, true);
            app.ReceivedEditField.Editable = 'off';
            app.ToSendEditFieldLabel = uilabel(app.UIFigure);
            app.ToSendEditFieldLabel.Text = 'To Send';
            app.ToSendEditField = uieditfield(app.UIFigure, 'text');
            app.ToSendEditField.ValueChangedFcn = createCallbackFcn(app, @ToSendEditFieldValueChanged, true);
            app.ToSendEditField.Editable = 'off';
            app.DisplayDropDownLabel = uilabel(app.UIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.UIFigure);
            app.DisplayDropDown.Items = {'ASCII', 'Decimal', 'Binary'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'ASCII';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App3_R2016b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end