classdef Sonar_Lidar_App_V_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Axes         matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
        SonarButton  matlab.ui.control.StateButton
        LidarButton  matlab.ui.control.StateButton
        OnOffButton  matlab.ui.control.StateButton
    end


    methods (Access = public)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Variable Creation And Setting
            global pos 
            pos = 1;
            global step;
            step = 3.75;
            global top;
            top = 31;
            global top_grade;
            top_grade = step*top;
            
            global theta;
            theta = -1*(-pi/2+pi/180*[-top_grade:step:top_grade]);
            
            
            global r_sonar;
            r_sonar = zeros(1,length(theta));

            global r_lidar;
            r_lidar = zeros(1,length(theta));

            
            global r_pos;
            r_pos = 90*ones(1,length(theta));

            
            %Port Creation
            global puerto;
            %Port Configuration
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻            
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            puerto=serial('COM8','BaudRate',115200,'Terminator','LF');   
            %Port Open
            fopen(puerto); 


        end

        % Button pushed function: StartButton
        function Loop(app, event)
            %Including Global Variables
            global r_pos;
            global theta;
            global r_lidar;
            global r_sonar;
            global pos;
            buffersize = 16;
            
            while  app.On_Off.Value;
                %Port Data Reading
                aux = fread(puerto,[1,buffersize],'uint8'); 
                
                %Port Flush
                flush=flush+1;
                
                if flush>2
                flushinput(puerto)
                flush = 0;
                end
                i = 1;
                
                %Data Get
                bin = dec2bin(aux);
                
                %Data Decode
                while i<(buffersize-4)
                   Sonar_Data = strcat(bin(i,3:8),bin(i+1,2:8),bin(i+3,4:8));
                   Lidar_Data = bin(i+4,2:8);
                   pos = bin(i,3:3);
                end
                
                i = i+5;
                %Here should Go The Necessary Data Preprocessing


                %Data Storage
                r_sonar(pos) = Sonar_Data;
                r_lidar(pos) = Lidar_Data;
                
                %Polar to Cartesian Coordinates Conversion
                [x_sonar, y_sonar] = pol2cart(theta,r_sonar);
            
                [x_lidar, y_lidar] = pol2cart(theta,r_lidar);
                
                [x_pos, y_pos] = pol2cart(theta,r_pos);    
                
                
                ax = app.Axes;
                
                %This Is The Blue Line
                plot(app.Axes,[0 x_pos(pos)],[0 y_pos(pos)],'b','Linewidth',2)
          
                %Ploting The Sonar 
                if app.Sonar.Value;
                    stem(app.Axes,x_sonar,y_sonar,'.','g','LineStyle','none')
                end
                
                %Ploting The Lidar
                if app.Lidar.Value;
                    stem(app.Axes,x_lidar,y_lidar,'.','y','LineStyle','none')                    
                end
                
                %Increase For The Port Flush Counter
                flush = flush+1;
                
                
            end
                
        end

        % Value changed function: LidarButton
        function Lidar(app, event)
            value = app.LidarButton.Value;
            
        end

        % Value changed function: SonarButton
        function Sonar(app, event)
            value = app.SonarButton.Value;
            
        end

        % Value changed function: OnOffButton
        function On_Off(app, event)
            value = app.OnOffButton.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            instrreset
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Le Graph')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.Axes.XLim = [-90 90];
            app.Axes.XLimMode = 'manual';
            app.Axes.YLim = [-50 90];
            app.Axes.YLimMode = 'manual';
            app.Axes.XAxisLocation = 'origin';
            app.Axes.XTick = [-80 -70 -60 -50 -40 -30 -20 -10 10 20 30 40 50 60 70 80];
            app.Axes.XTickLabel = {'-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.Axes.YAxisLocation = 'origin';
            app.Axes.YTick = [-40 -30 -20 -10 10 20 30 40 50 60 70 80];
            app.Axes.YTickLabel = {'-40'; '-30'; '-20'; '-10'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Loop, true);
            app.StartButton.Text = 'Start';
            app.SonarButton = uibutton(app.UIFigure, 'state');
            app.SonarButton.ValueChangedFcn = createCallbackFcn(app, @Sonar, true);
            app.SonarButton.Text = 'Sonar';
            app.LidarButton = uibutton(app.UIFigure, 'state');
            app.LidarButton.ValueChangedFcn = createCallbackFcn(app, @Lidar, true);
            app.LidarButton.Text = 'Lidar';
            app.OnOffButton = uibutton(app.UIFigure, 'state');
            app.OnOffButton.ValueChangedFcn = createCallbackFcn(app, @On_Off, true);
            app.OnOffButton.Text = 'On/Off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sonar_Lidar_App_V_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end