classdef Sonar_Lidar_App_V_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Axes         matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
        SonarButton  matlab.ui.control.StateButton
        LidarButton  matlab.ui.control.StateButton
        OnOffButton  matlab.ui.control.StateButton
    end


    methods (Access = public)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Variable Creation And Setting
            global pos 
            pos = 1;
            global step;
            step = 3.75;
            global top;
            top = 31;
            global top_grade;
            top_grade = step*top;
            
            global theta;
            theta = -1*(-pi/2+pi/180*[-top_grade:step:top_grade]);
            
            
            global r_sonar;
            r_sonar = zeros(1,length(theta));

            global r_lidar;
            r_lidar = zeros(1,length(theta));

            
            global r_pos;
            r_pos = 90*ones(1,length(theta));

            
            %Port Creation
            global puerto;
            %Port Configuration
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻            
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            %JIANLUK CHANGE THE PORT TO THE ONE YOU USEEEEEEEEEEEEEEEEEEEEEEEEEEE
            %(┛◉Д◉)┛彡┻━┻
            puerto=serial('COM8','BaudRate',9600,'Terminator','LF');   
            %Port Open
            fopen(puerto); 


        end

        % Value changed function: LidarButton
        function Lidar(app, event)
            global boton_lidar
            value = app.LidarButton.Value;
            boton_lidar=value
            global theta
            global r_lidar
            r_lidar = zeros(1,length(theta));
      
        end

        % Button pushed function: StartButton
        function Loop(app, event)
            %Including Global Variables
            global puerto;
            global r_pos;
            global theta;
            global r_lidar;
            global r_sonar;
            global pos;
            global boton_on;
            global boton_sonar;
            global boton_lidar;
            global flush
            flush=0;
            global x_sonar
            global y_sonar
            global x_lidar
            global y_lidar
            
            buffersize = 80;


            while  boton_on~=0;
                %Port Data Reading
                aux = fread(puerto,[1,buffersize],'uint8'); 
                
                %Port Flush
                flush=flush+1;
                
                if flush>90
                flushinput(puerto)
                flush = 0;
                end
                i = 1;
                
                %Data Get
                bin = dec2bin(aux);

                %Data Decode
                while i<(buffersize-4)
                   Sonar_Data = strcat(bin(i+1,3:8),bin(i+2,2:3));
                  
                   Lidar_Data = strcat(bin(i+2,4:8),bin(i+3,2:8));
                   
                   pos = bin(i,3:8);
                  i=i+4;
                  
                %end
                boton_lidar
                boton_on
                boton_sonar             

                dpos=bin2dec(pos)
               
               
                %Here should Go The Necessary Data Preprocessing


                %Data Storage
                r_sonar(dpos) = bin2dec(Sonar_Data);
                r_lidar(dpos) = bin2dec(Lidar_Data);
                
                %Polar to Cartesian Coordinates Conversion
                [x_sonar, y_sonar] = pol2cart(theta,r_sonar);
            
                [x_lidar, y_lidar] = pol2cart(theta,r_lidar);
                
                [x_pos, y_pos] = pol2cart(theta,r_pos);    


                %This Is The Blue Line
                plot(app.Axes,[0 x_pos(dpos)],[0 y_pos(dpos)],'w','Linewidth',2)
                
                %Ploting The Sonar 
                if boton_sonar~=0;

                    stem(app.Axes,x_sonar,y_sonar,'.','g','LineStyle','none')
              
                    
                end
                
                %Ploting The Lidar
                if boton_lidar~=0;
                    stem(app.Axes,x_lidar,y_lidar,'.','b','LineStyle','none') 
                   
                    
                end
                
                drawnow;
                %Increase For The Port Flush Counter
                end
                drawnow
                
            end
                
        end

        % Value changed function: OnOffButton
        function On_Off(app, event)
            global boton_on;
            value = app.OnOffButton.Value;
            boton_on = value

        end

        % Value changed function: SonarButton
        function Sonar(app, event)
            global boton_sonar;
            value = app.SonarButton.Value;
            boton_sonar=value
            global r_sonar
            global theta
            r_sonar = zeros(1,length(theta));
 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            instrreset
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Le Graph')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.Axes.XLim = [-90 90];
            app.Axes.YLim = [-50 90];
            app.Axes.XAxisLocation = 'origin';
            app.Axes.XTick = [-80 -70 -60 -50 -40 -30 -20 -10 10 20 30 40 50 60 70 80];
            app.Axes.XTickLabel = {'-80'; '-70'; '-60'; '-50'; '-40'; '-30'; '-20'; '-10'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.Axes.YAxisLocation = 'origin';
            app.Axes.YTick = [-40 -30 -20 -10 10 20 30 40 50 60 70 80];
            app.Axes.YTickLabel = {'-40'; '-30'; '-20'; '-10'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Loop, true);
            app.StartButton.Text = 'Start';
            app.SonarButton = uibutton(app.UIFigure, 'state');
            app.SonarButton.ValueChangedFcn = createCallbackFcn(app, @Sonar, true);
            app.SonarButton.Text = 'Sonar';
            app.LidarButton = uibutton(app.UIFigure, 'state');
            app.LidarButton.ValueChangedFcn = createCallbackFcn(app, @Lidar, true);
            app.LidarButton.Text = 'Lidar';
            app.OnOffButton = uibutton(app.UIFigure, 'state');
            app.OnOffButton.ValueChangedFcn = createCallbackFcn(app, @On_Off, true);
            app.OnOffButton.Text = 'On/Off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sonar_Lidar_App_V_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end